classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SelectButton      matlab.ui.control.Button
        SelectImageLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [file,path,index] = uigetfile({'*.jpg;*.png;*.jpeg'});
            if isequal(file,0)
                disp(' User Selected Cancel')
            else
                disp(['User Selected ' , fullfile(path,file), 'and filter index: ',num2str(index)]);
                img=imread(fullfile(path,file));
                imshow(img)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectImageLabel = uilabel(app.UIFigure);
            app.SelectImageLabel.Text = 'Select Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end